classdef annotation_app_fixed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImagetypeDropDown            matlab.ui.control.DropDown
        ImagetypeDropDownLabel       matlab.ui.control.Label
        NextUnseenButton             matlab.ui.control.Button
        SaveAnnotationsButton        matlab.ui.control.Button
        X_valY_valLamp               matlab.ui.control.Lamp
        X_valY_valLampLabel          matlab.ui.control.Label
        loadButton                   matlab.ui.control.Button
        DatasetFolderEditField       matlab.ui.control.EditField
        DatasetFolderEditFieldLabel  matlab.ui.control.Label
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
    end

    
    properties (Access = public)
        files    
        curr_file
        results
        image
        res_path
        file_type
    end
    
    methods (Access = private)
        
        function updateimage(app)
            this_file = app.files(app.curr_file);
            im = imread(fullfile(this_file.folder, this_file.name));
            app.image = imagesc(app.ImageAxes,im);   
            app.ImageAxes.Title.String = this_file.name;
            app.ImageAxes.Title.Visible = 'on';
            app.image.ButtonDownFcn = createCallbackFcn(app, @ImageAxesButtonDown, true);
            app.X_valY_valLampLabel.Text = this_file.name;
            if isKey(app.results,this_file.name)
                res = app.results(this_file.name);
                if res(3)
                    app.X_valY_valLamp.Color = [0, 1, 0];
                else
                    app.X_valY_valLamp.Color = [1, 0, 0];
                end
                app.X_valY_valLampLabel.Text = sprintf("%d, %d", res(1), res(2));
            else
                app.X_valY_valLamp.Color = [1, 0, 0];
                app.X_valY_valLampLabel.Text = "None, None";
            end

        end

        function folder_from_path(app, path)
            folderpath = strsplit(path,filesep);
            folderpath = string(folderpath{end});
            app.res_path = folderpath + ".mat";
            app.file_type = '*.png';
            if isfile(app.res_path)
                load(app.res_path, 'results');
                app.results = results;
            else
                app.results = containers.Map('KeyType','char', 'ValueType', 'any');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.folder_from_path(app.DatasetFolderEditField.Value);
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            if ~isempty(app.files)
                app.files = natsortfiles(dir(fullfile(app.DatasetFolderEditField.Value, app.file_type)));
                app.curr_file = 1;
                updateimage(app);
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            app.file_type = "*." + string(app.ImagetypeDropDown.Value);
            % Update the image and histograms
            updateimage(app, app.ImagetypeDropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.curr_file < length(app.files)
                app.curr_file = app.curr_file  + 1;
                updateimage(app);
            else
                msgbox('Done!');
            end
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            app.folder_from_path(app.DatasetFolderEditField.Value);
            app.files = dir(fullfile(app.DatasetFolderEditField.Value, app.file_type));
            app.curr_file = 1;
            updateimage(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.curr_file > 1 
                app.curr_file = app.curr_file - 1;
                updateimage(app);
            else
                msgbox('This is the first image!');
            end
        end

        % Button down function: ImageAxes
        function ImageAxesButtonDown(app, event)
             A = event.IntersectionPoint;
             x = round(A(1));
             y = round(A(2));
             app.results(app.files(app.curr_file).name) = [x, y, 1];
             updateimage(app);
        end

        % Callback function
        function ImageAxes_2ButtonDown(app, event)
            msgbox('You clicked!');
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if contains(key, 'return')
                NextButtonPushed(app, event);
            elseif contains(key, 'backspace')
                PreviousButtonPushed(app, event);
            end

        end

        % Button pushed function: SaveAnnotationsButton
        function SaveAnnotationsButtonPushed(app, event)
           results = app.results;
           save('annotations', 'results');
        end

        % Button pushed function: NextUnseenButton
        function NextUnseenButtonPushed(app, event)
             if app.curr_file < length(app.files)
                 app.curr_file = app.curr_file + 1;
             else
                 msgbox("All images have been annotated!");
             end
             this_file = 1;
             while this_file < length(app.files) && isKey(app.results,app.files(this_file).name)
                 result = app.results(app.files(this_file).name);
                 if result(3)
                     this_file = this_file + 1;
                 else
                     break;
                 end
             end
             app.curr_file = this_file;
             updateimage(app);
        end

        % Value changed function: ImagetypeDropDown
        function ImagetypeDropDownValueChanged(app, event)
            value = app.ImagetypeDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @ImageAxesButtonDown, true);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.DatasetFolderEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetFolderEditFieldLabel.Text = 'Dataset Folder';
            app.DatasetFolderEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetFolderEditField.Value = 'C:\Users\ssohr\OneDrive\Documents\eye-project\dataset\Training_Set\Training';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.X_valY_valLampLabel = uilabel(app.UIFigure);
            app.X_valY_valLampLabel.Text = 'X_val, Y_val';
            app.X_valY_valLamp = uilamp(app.UIFigure);
            app.SaveAnnotationsButton = uibutton(app.UIFigure, 'push');
            app.SaveAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationsButtonPushed, true);
            app.SaveAnnotationsButton.Text = 'Save Annotations';
            app.NextUnseenButton = uibutton(app.UIFigure, 'push');
            app.NextUnseenButton.ButtonPushedFcn = createCallbackFcn(app, @NextUnseenButtonPushed, true);
            app.NextUnseenButton.Text = 'Next Unseen';
            app.ImagetypeDropDownLabel = uilabel(app.UIFigure);
            app.ImagetypeDropDownLabel.Text = 'Image type';
            app.ImagetypeDropDown = uidropdown(app.UIFigure);
            app.ImagetypeDropDown.Items = {'png', 'tif', 'jpg', ''};
            app.ImagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagetypeDropDownValueChanged, true);
            app.ImagetypeDropDown.Value = 'png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.ImageAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotation_app_fixed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end